classdef projet < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Label_2                      matlab.ui.control.Label
        StageofCancerLabel           matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        CLEARIMAGESButton            matlab.ui.control.Button
        PourcentageofinfectionLabel  matlab.ui.control.Label
        doneButton                   matlab.ui.control.Button
        DetectionButton              matlab.ui.control.Button
        MaskButton                   matlab.ui.control.Button
        BinaraiseButton              matlab.ui.control.Button
        BrowseButton                 matlab.ui.control.Button
        Image2                       matlab.ui.control.Image
        UIAxes_3                     matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)

                [file, path] = uigetfile('*.*', 'Select a File');
                disp(['Selected file: ', fullfile(path, file)]);
         
                selectedImage = imread(fullfile(path, file));

              
                app.Image2.ImageSource = selectedImage;
               

        end

        % Button pushed function: BinaraiseButton
        function BinaraiseButtonPushed(app, event)

                
                selectedImage = app.Image2.ImageSource;
            
               
                GrayA = rgb2gray(selectedImage);
            
                
                bin = imbinarize(GrayA);
            
                
                imshow(bin, 'Parent', app.UIAxes_3)

        end

        % Button pushed function: MaskButton
        function MaskButtonPushed(app, event)
            x = rgb2lab(app.Image2.ImageSource);
            [l,a,b] = imsplit(x);
   
            bluemask = b < -30;
            imshow(bluemask, 'Parent', app.UIAxes_2)
        end

        % Button pushed function: DetectionButton
        function DetectionButtonPushed(app, event)
            x = rgb2lab(app.Image2.ImageSource);
            [l,a,b] = imsplit(x);
   
            bluemask = b < -30;
            cancer = imoverlay(app.Image2.ImageSource,bluemask,'red');

            imshow(cancer, 'Parent', app.UIAxes2)
        end

        % Button pushed function: doneButton
        function doneButtonPushed(app, event)
            
            
            net = load('net.mat');


            selectedImage = app.Image2.ImageSource;
            A_resized = imresize(selectedImage, [256, 256]);
            
            GrayA = rgb2gray(selectedImage);

            bin = imbinarize(GrayA);

            x = rgb2lab(app.Image2.ImageSource);
            [l,a,b] = imsplit(x);
   
            bluemask = b < -30;
            
            count = bwconncomp(bluemask);
            num = count.NumObjects;
            
            count = bwconncomp(bin);
            numall = count.NumObjects;
            
            pourcentage = (num/numall)*100;

            app.Label.Text = [num2str(pourcentage) '%'];
       
            app.Label_2.Text = classify(net, im2double(A_resized));
        end

        % Button pushed function: CLEARIMAGESButton
        function CLEARIMAGESButtonPushed(app, event)
            cla(app.UIAxes2);
            cla(app.UIAxes_2);
            cla(app.UIAxes);

            app.Image2.ImageSource = '';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes_3 = uiaxes(app.UIFigure);
            app.Image2 = uiimage(app.UIFigure);
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.BinaraiseButton = uibutton(app.UIFigure, 'push');
            app.BinaraiseButton.ButtonPushedFcn = createCallbackFcn(app, @BinaraiseButtonPushed, true);
            app.BinaraiseButton.Text = 'Binaraise';
            app.MaskButton = uibutton(app.UIFigure, 'push');
            app.MaskButton.ButtonPushedFcn = createCallbackFcn(app, @MaskButtonPushed, true);
            app.MaskButton.Text = 'Mask';
            app.DetectionButton = uibutton(app.UIFigure, 'push');
            app.DetectionButton.ButtonPushedFcn = createCallbackFcn(app, @DetectionButtonPushed, true);
            app.DetectionButton.Text = 'Detection';
            app.doneButton = uibutton(app.UIFigure, 'push');
            app.doneButton.ButtonPushedFcn = createCallbackFcn(app, @doneButtonPushed, true);
            app.doneButton.Text = 'done';
            app.PourcentageofinfectionLabel = uilabel(app.UIFigure);
            app.PourcentageofinfectionLabel.Text = 'Pourcentage of infection :       ';
            app.CLEARIMAGESButton = uibutton(app.UIFigure, 'push');
            app.CLEARIMAGESButton.ButtonPushedFcn = createCallbackFcn(app, @CLEARIMAGESButtonPushed, true);
            app.CLEARIMAGESButton.Text = 'CLEAR IMAGES';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '%';
            app.StageofCancerLabel = uilabel(app.UIFigure);
            app.StageofCancerLabel.Text = 'Stage of Cancer';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = projet

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end